classdef BDP_Eksamensopgave_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BasicPanel                    matlab.ui.container.Panel
        origImageAxes                 matlab.ui.control.UIAxes
        OpenImageButton               matlab.ui.control.Button
        SegmentButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        AdvancedPanel                 matlab.ui.container.Panel
        whiteBloodCellAxes            matlab.ui.control.UIAxes
        redBloodCellAxes              matlab.ui.control.UIAxes
        whiteBloodCellNumber          matlab.ui.control.Label
        AdjustcoloursforsegmentingPanel  matlab.ui.container.Panel
        ThresholdLabel                matlab.ui.control.Label
        whiteCellThreshold            matlab.ui.control.Slider
        Thresholdlabel                matlab.ui.control.Label
        AdjustcoloursforsegmentingPanel_2  matlab.ui.container.Panel
        RedshadowsLabel               matlab.ui.control.Label
        RedShadow                     matlab.ui.control.Slider
        RedhighlightsLabel            matlab.ui.control.Label
        RedHighLight                  matlab.ui.control.Slider
        GreenshadowsLabel             matlab.ui.control.Label
        GreenShadow                   matlab.ui.control.Slider
        GreenhighlightsLabel          matlab.ui.control.Label
        GreenHighLight                matlab.ui.control.Slider
        BlueshadowsLabel              matlab.ui.control.Label
        BlueShadow                    matlab.ui.control.Slider
        BluehighlightsLabel           matlab.ui.control.Label
        BlueHighLight                 matlab.ui.control.Slider
        SensitivitySlider_2Label      matlab.ui.control.Label
        SensitivitySlider             matlab.ui.control.Slider
        EdgeThresholdSlider_2Label    matlab.ui.control.Label
        EdgeThresholdSlider           matlab.ui.control.Slider
        SegmentationInfoPanel         matlab.ui.container.Panel
        NumberofwhiteblodcellsLabel   matlab.ui.control.Label
        NumberofredblodcellsLabel     matlab.ui.control.Label
        AveragewhitebloodcellssizeLabel  matlab.ui.control.Label
        RedbloodcellvariationLabel    matlab.ui.control.Label
        RelativewhitebloodcellsLabel  matlab.ui.control.Label
        WhitebloodcellvariationLabel  matlab.ui.control.Label
        AverageredbloodcellsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        % These are the properties that can be accessed through the app variable.
        FileName;
        ImageData;
        bw;
        whiteMask;
        redMask;
        centers;
        radii;
        metric;
        imageLoaded;
        numberOfWhiteBloodCells;
        numberOfRedBloodCells;
        allRedCellSizes;
        maximumRedCellSize;
        minimumRedCellSize;
        averageRedCellSize;
        maximumWhiteCellSize;
        minimumWhiteCellSize;
        averageWhiteCellSize;
        whiteCellPercentage;
    end
    
    methods (Access = private)
        
        function findWhiteBloodCells(app, threshold)
                % Value found by using different numbers. 7 Works best.    
                radius = 7;
                greyImg = rgb2gray(app.ImageData);
                % Function created by using 'image segmenter'
                [app.bw, maskedImage] = segmentImage(greyImg, threshold, radius);
                app.bw = ~app.bw;
                % Removes objects that are smaller than 300 pixels
                app.bw  = bwareaopen(app.bw, 300);
                % Fill all holes in remaining objects
                app.bw = imfill(app.bw,'holes');
                imshow(app.bw,'Parent',app.whiteBloodCellAxes);
                % Indexes all the remaining objects in numerical values and returns the highest number
                app.numberOfWhiteBloodCells = max(max(bwlabel(app.bw)));
                textToDisplay = sprintf('Number of white blodcells: %d', app.numberOfWhiteBloodCells);
                app.NumberofwhiteblodcellsLabel.Text = textToDisplay;
                calculateWhiteCellSize(app);
        end
        
        function findRedBloodCells(app, red1, red2, green1, green2, blue1, blue2, sensitivity, edgeTreshold)
            % Validate that the user has selected an image.
            if(app.imageLoaded)
                % Function created by using 'Color Thresholder'
                app.redMask = createMask(app.ImageData, red1, red2, green1, green2, blue1, blue2);
                % Function created by using 'Circle Finder'
                detectCircles = @(x) imfindcircles(x,[16 50], ...
                'Sensitivity', sensitivity, ...
                'EdgeThreshold', edgeTreshold, ...
                'Method', 'TwoStage', ...
                'ObjectPolarity', 'Bright');
            
                [app.centers, app.radii, app.metric] = detectCircles(app.redMask);
                
                imshow(app.redMask, 'Parent', app.redBloodCellAxes);
                createCircles(app);
                app.numberOfRedBloodCells = length(app.centers);
                string = sprintf('Number of red blodcells: %d', app.numberOfRedBloodCells);
                app.NumberofredblodcellsLabel.Text = string;
                
                calculateRedCellSize(app);
            end
        end
        
        function createCircles(app)
                % Gets all information about the red blood cells (radius & center)
                % and uses it to create cirles
                t = linspace(0,2*pi, 200);
                hold(app.redBloodCellAxes);                
                for i= 1:length(app.centers)
                    xunit = app.radii(i) * cos(t) + app.centers(i,1);
                    yunit = app.radii(i) * sin(t) + app.centers(i,2);
                    plot(xunit, yunit, 'Parent', app.redBloodCellAxes, ...
                        "Color","Red", ...
                        "LineWidth", 1.5, ...
                        "LineStyle","-");
                end
                
                % Limits our axis' to fit it within our current image
                app.redBloodCellAxes.XLim = app.whiteBloodCellAxes.XAxis.Limits;
                app.redBloodCellAxes.YLim = app.whiteBloodCellAxes.YAxis.Limits;
        end
        
        function calculateRelativeNumbers(app)
                app.whiteCellPercentage = (app.numberOfWhiteBloodCells/(app.numberOfRedBloodCells))*100;
                string = sprintf('Relative white blood cells: %.2f%%', app.whiteCellPercentage);
                app.RelativewhitebloodcellsLabel.Text = string;
        end
        
        function calculateRedCellSize(app)
            % Returns array of cell areas
            app.allRedCellSizes = double(app.radii(:) * 0.15).^2*pi;
            
            % The minimum and maximum is found in order to find variance
            app.minimumRedCellSize = min(app.allRedCellSizes);
            app.averageRedCellSize = mean(app.allRedCellSizes);
            app.maximumRedCellSize = max(app.allRedCellSizes);
            
            string = sprintf('Red blood cell variation: %.2f µm^2', app.maximumRedCellSize - app.minimumRedCellSize);
            app.RedbloodcellvariationLabel.Text = string;
            
            string = sprintf('Average red blood cells size: %.2f µm^2', app.averageRedCellSize);
            app.AverageredbloodcellsLabel.Text = string;
        end
        
        function calculateWhiteCellSize(app)
            % Used to find each remaining cell
            labels = bwlabel(app.bw);
            % Prepopulate to make the code faster
            Area = zeros(1,app.numberOfWhiteBloodCells);
            
            % Regionprops is used to find the area of each remaining cell
            for i = 1: app.numberOfWhiteBloodCells
                RP = regionprops(labels==i);
                Area(i) = RP.Area;
            end
            
            % The minimum and maximum is found in order to find variance
            app.minimumWhiteCellSize = min(Area) * (0.15) * (0.15);
            app.averageWhiteCellSize = mean(Area) * (0.15) * (0.15);
            app.maximumWhiteCellSize = max(Area) * (0.15) * (0.15);
        
            string = sprintf('White blood cell variation: %.2f µm^2', app.maximumWhiteCellSize - app.minimumWhiteCellSize);
            app.WhitebloodcellvariationLabel.Text = string;
            
            string = sprintf('Average white blood cells size: %.2f µm^2', app.averageWhiteCellSize);
            app.AveragewhitebloodcellssizeLabel.Text = string;

        end
        
        function saveDataAsTxtFile(app)
            fileID = fopen('data.txt','w');
            fprintf(fileID,[ 'File name: %s \n' ...
                             'Red cells: %d \n' ...
                             'White cells: %d \n' ...
                             'Red variance: %.2f \n' ...
                             'White variance: %.2f \n' ...
                             'Percentage distribution: %.2f \n' ...
                             'avg.red: %.2f \n' ...
                             'avg.white: %.2f \n' ...
                             'Thresholdlabel: %.2f \n' ...
                             'Red shadows: %.2f \n' ...
                             'Red highligts: %.2f \n' ...
                             'Blue shadows: %.2f \n' ...
                             'Blue highlights: %.2f \n' ...
                             'Green shadows: %.2f \n' ...
                             'Green highlights: %.2f \n' ...
                             'Sensitivity: %.2f \n' ...
                             'Edge treshold: %.2f \n'], ...
                             app.FileName, app.numberOfRedBloodCells, app.numberOfWhiteBloodCells, ...
                             app.maximumRedCellSize - app.minimumRedCellSize, ...
                             app.maximumWhiteCellSize - app.minimumWhiteCellSize, ...
                             app.whiteCellPercentage, app.averageRedCellSize, app.averageWhiteCellSize, ...
                             app.whiteCellThreshold.Value, app.RedShadow.Value, app.RedHighLight.Value, ...
                             app.BlueShadow.Value, app.BlueHighLight.Value, ...
                             app.GreenShadow.Value, app.GreenHighLight.Value, ...
                             app.SensitivitySlider.Value, app.EdgeThresholdSlider.Value);
            fclose(fileID);
        end
        
        function saveDataAsCSVFile(app)
            DataToFile = {app.FileName, app.numberOfRedBloodCells, app.numberOfWhiteBloodCells,...
                round(app.maximumRedCellSize-app.minimumRedCellSize,2),...
                round(app.maximumWhiteCellSize-app.minimumWhiteCellSize,2),...
                round(app.whiteCellPercentage,2), round(app.averageRedCellSize,2), app.averageWhiteCellSize,...
                app.whiteCellThreshold.Value, app.RedShadow.Value, app.RedHighLight.Value,...
                app.BlueShadow.Value, app.BlueHighLight.Value,...
                app.GreenShadow.Value, app.GreenHighLight.Value,...
                app.SensitivitySlider.Value, app.EdgeThresholdSlider.Value};
            DataToTable = cell2table(DataToFile);
            DataToTable.Properties.VariableNames = {'FileName','RedCells','WhiteCells',...
                                                    'RedVariance', 'WhiteVariance',...
                                                    'PercentDistribution', 'AvgRedcells',...
                                                    'AvgWhitecells', 'Thresholdlabel',...
                                                    'Redshadows', 'Redhighlights', 'Blueshadows', 'Bluehighlights',...
                                                    'Greenshadows', 'Greenhighlights', 'Sensitivity', 'EdgeTreshold'};
            writetable(DataToTable, 'Data.csv', 'Delimiter', ';','WriteVariableNames',true);
        end
        
        function AdjustRedBloodcellData(app)
            % This is called whenever whenever you adjust a slider
            findRedBloodCells(app, app.RedShadow.Value, app.RedHighLight.Value,...
                app.GreenShadow.Value, app.GreenHighLight.Value,...
                app.BlueShadow.Value, app.BlueHighLight.Value,...
                app.SensitivitySlider.Value, app.EdgeThresholdSlider.Value);
        end
    end
    

    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            % Limits the formats the user can choose
            [app.FileName,Pathname] = uigetfile({'*.bmp;*.jpg; *.png'},...
                          'Choose an image to open');
                      % If an image has been selected it will update the UI
                      if app.FileName~=0
                          app.FileName = fullfile(Pathname,app.FileName);
                          app.ImageData=imread(app.FileName);
                          imshow(app.ImageData,'Parent',app.origImageAxes);
                          app.imageLoaded = true;
                      end
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            findWhiteBloodCells(app, app.whiteCellThreshold.Value);
            
            AdjustRedBloodcellData(app);
            calculateRelativeNumbers(app);
        end

        % Value changed function: whiteCellThreshold
        function whiteCellThresholdValueChanged(app, event)
            value = app.whiteCellThreshold.Value;
            % Validate that the user has selected an image.
            if(app.imageLoaded)
                findWhiteBloodCells(app,value);
                calculateWhiteCellSize(app);
                calculateRelativeNumbers(app);
            end
        end

        % Value changing function: whiteCellThreshold
        function whiteCellThresholdValueChanging(app, event)
            changingValue = event.Value;
            roundedValue = round(changingValue,2);
            app.Thresholdlabel.Text = string(roundedValue);
        end

        % Value changed function: RedShadow
        function RedShadowValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: RedHighLight
        function RedHighLightValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: GreenShadow
        function GreenShadowValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: GreenHighLight
        function GreenHighLightValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: BlueShadow
        function BlueShadowValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: BlueHighLight
        function BlueHighLightValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Value changed function: EdgeThresholdSlider
        function EdgeThresholdSliderValueChanged(app, event)
             AdjustRedBloodcellData(app);
             calculateRelativeNumbers(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveDataAsTxtFile(app);
            saveDataAsCSVFile(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BasicPanel = uipanel(app.UIFigure);
            app.origImageAxes = uiaxes(app.BasicPanel);
            title(app.origImageAxes, 'Original Image')
            app.origImageAxes.XTick = [];
            app.origImageAxes.YTick = [];
            app.OpenImageButton = uibutton(app.BasicPanel, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.SegmentButton = uibutton(app.BasicPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.SaveButton = uibutton(app.BasicPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AdvancedPanel = uipanel(app.UIFigure);
            app.whiteBloodCellAxes = uiaxes(app.AdvancedPanel);
            title(app.whiteBloodCellAxes, 'White Blood Cells')
            app.whiteBloodCellAxes.XTick = [];
            app.whiteBloodCellAxes.YTick = [];
            app.redBloodCellAxes = uiaxes(app.AdvancedPanel);
            title(app.redBloodCellAxes, 'Red Blood Cells')
            xlabel(app.redBloodCellAxes, '')
            app.redBloodCellAxes.XTick = [];
            app.redBloodCellAxes.YTick = [];
            app.whiteBloodCellNumber = uilabel(app.AdvancedPanel);
            app.whiteBloodCellNumber.Text = '';
            app.AdjustcoloursforsegmentingPanel = uipanel(app.AdvancedPanel);
            app.AdjustcoloursforsegmentingPanel.Title = 'Adjust colours for segmenting';
            app.ThresholdLabel = uilabel(app.AdjustcoloursforsegmentingPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.whiteCellThreshold = uislider(app.AdjustcoloursforsegmentingPanel);
            app.whiteCellThreshold.Limits = [0 255];
            app.whiteCellThreshold.ValueChangedFcn = createCallbackFcn(app, @whiteCellThresholdValueChanged, true);
            app.whiteCellThreshold.ValueChangingFcn = createCallbackFcn(app, @whiteCellThresholdValueChanging, true);
            app.whiteCellThreshold.Value = 120;
            app.Thresholdlabel = uilabel(app.AdjustcoloursforsegmentingPanel);
            app.Thresholdlabel.Text = '';
            app.AdjustcoloursforsegmentingPanel_2 = uipanel(app.AdvancedPanel);
            app.AdjustcoloursforsegmentingPanel_2.Title = 'Adjust colours for segmenting';
            app.RedshadowsLabel = uilabel(app.AdjustcoloursforsegmentingPanel_2);
            app.RedshadowsLabel.Text = 'Red shadows';
            app.RedShadow = uislider(app.AdjustcoloursforsegmentingPanel_2);
            app.RedShadow.Limits = [0 250];
            app.RedShadow.ValueChangedFcn = createCallbackFcn(app, @RedShadowValueChanged, true);
            app.RedhighlightsLabel = uilabel(app.AdjustcoloursforsegmentingPanel_2);
            app.RedhighlightsLabel.Text = 'Red highlights';
            app.RedHighLight = uislider(app.AdjustcoloursforsegmentingPanel_2);
            app.RedHighLight.Limits = [0 250];
            app.RedHighLight.ValueChangedFcn = createCallbackFcn(app, @RedHighLightValueChanged, true);
            app.RedHighLight.Value = 210;
            app.GreenshadowsLabel = uilabel(app.AdjustcoloursforsegmentingPanel_2);
            app.GreenshadowsLabel.Text = 'Green shadows';
            app.GreenShadow = uislider(app.AdjustcoloursforsegmentingPanel_2);
            app.GreenShadow.Limits = [0 250];
            app.GreenShadow.ValueChangedFcn = createCallbackFcn(app, @GreenShadowValueChanged, true);
            app.GreenShadow.Value = 135;
            app.GreenhighlightsLabel = uilabel(app.AdjustcoloursforsegmentingPanel_2);
            app.GreenhighlightsLabel.Text = 'Green highlights';
            app.GreenHighLight = uislider(app.AdjustcoloursforsegmentingPanel_2);
            app.GreenHighLight.Limits = [0 250];
            app.GreenHighLight.ValueChangedFcn = createCallbackFcn(app, @GreenHighLightValueChanged, true);
            app.GreenHighLight.Value = 242;
            app.BlueshadowsLabel = uilabel(app.AdjustcoloursforsegmentingPanel_2);
            app.BlueshadowsLabel.Text = 'Blue shadows';
            app.BlueShadow = uislider(app.AdjustcoloursforsegmentingPanel_2);
            app.BlueShadow.Limits = [0 250];
            app.BlueShadow.ValueChangedFcn = createCallbackFcn(app, @BlueShadowValueChanged, true);
            app.BlueShadow.Value = 158;
            app.BluehighlightsLabel = uilabel(app.AdjustcoloursforsegmentingPanel_2);
            app.BluehighlightsLabel.Text = 'Blue highlights';
            app.BlueHighLight = uislider(app.AdjustcoloursforsegmentingPanel_2);
            app.BlueHighLight.Limits = [0 250];
            app.BlueHighLight.ValueChangedFcn = createCallbackFcn(app, @BlueHighLightValueChanged, true);
            app.BlueHighLight.Value = 187;
            app.SensitivitySlider_2Label = uilabel(app.AdvancedPanel);
            app.SensitivitySlider_2Label.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.AdvancedPanel);
            app.SensitivitySlider.Limits = [0 1];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 0.8;
            app.EdgeThresholdSlider_2Label = uilabel(app.AdvancedPanel);
            app.EdgeThresholdSlider_2Label.Text = 'EdgeThreshold';
            app.EdgeThresholdSlider = uislider(app.AdvancedPanel);
            app.EdgeThresholdSlider.Limits = [0 1];
            app.EdgeThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeThresholdSliderValueChanged, true);
            app.EdgeThresholdSlider.Value = 0.013;
            app.SegmentationInfoPanel = uipanel(app.UIFigure);
            app.SegmentationInfoPanel.Title = 'Segmentation Info';
            app.NumberofwhiteblodcellsLabel = uilabel(app.SegmentationInfoPanel);
            app.NumberofwhiteblodcellsLabel.Text = 'Number of white blodcells:';
            app.NumberofredblodcellsLabel = uilabel(app.SegmentationInfoPanel);
            app.NumberofredblodcellsLabel.Text = 'Number of red blodcells:';
            app.AveragewhitebloodcellssizeLabel = uilabel(app.SegmentationInfoPanel);
            app.AveragewhitebloodcellssizeLabel.Text = 'Average white blood cells: size';
            app.RedbloodcellvariationLabel = uilabel(app.SegmentationInfoPanel);
            app.RedbloodcellvariationLabel.Text = 'Red blood cell variation:';
            app.RelativewhitebloodcellsLabel = uilabel(app.SegmentationInfoPanel);
            app.RelativewhitebloodcellsLabel.Text = 'Relative white blood cells:';
            app.WhitebloodcellvariationLabel = uilabel(app.SegmentationInfoPanel);
            app.WhitebloodcellvariationLabel.Text = 'White blood cell variation:';
            app.AverageredbloodcellsLabel = uilabel(app.SegmentationInfoPanel);
            app.AverageredbloodcellsLabel.Text = 'Average red blood cells:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BDP_Eksamensopgave_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end